classdef fibreFinder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SavecurrentsettingsMenu         matlab.ui.container.Menu
        LoadsettingsMenu                matlab.ui.container.Menu
        SavecurrentviewMenu             matlab.ui.container.Menu
        Currentcalibrationfactor1Label  matlab.ui.control.Label
        CalibratefibrewidthsButton      matlab.ui.control.Button
        ProcessbatchButton              matlab.ui.control.Button
        DisplayPanel                    matlab.ui.container.Panel
        RemovebackgroundCheckBox        matlab.ui.control.CheckBox
        FibrecolourmapDropDown          matlab.ui.control.DropDown
        FibrecolourmapDropDownLabel     matlab.ui.control.Label
        FibredataDropDown               matlab.ui.control.DropDown
        FibredataDropDownLabel          matlab.ui.control.Label
        AddscalebarCheckBox             matlab.ui.control.CheckBox
        VisualisationDropDown           matlab.ui.control.DropDown
        VisualisationDropDownLabel      matlab.ui.control.Label
        ProcessingparametersPanel       matlab.ui.container.Panel
        UpsampleddxEditField            matlab.ui.control.NumericEditField
        UpsampleddxEditFieldLabel       matlab.ui.control.Label
        OrientfibrescircularCheckBox    matlab.ui.control.CheckBox
        WatershedEdit                   matlab.ui.control.NumericEditField
        MaximumridgeEdit                matlab.ui.control.NumericEditField
        MinimumridgeEdit                matlab.ui.control.NumericEditField
        FlattenEdit                     matlab.ui.control.NumericEditField
        OrientfibreslinearCheckBox      matlab.ui.control.CheckBox
        WatershedthresholdSlider        matlab.ui.control.Slider
        WatershedthresholdLabel         matlab.ui.control.Label
        SplitbranchesCheckBox           matlab.ui.control.CheckBox
        InvertheightsCheckBox           matlab.ui.control.CheckBox
        FlatteningscaleSlider           matlab.ui.control.Slider
        FlatteningscaleSliderLabel      matlab.ui.control.Label
        MaximumridgescaleSlider         matlab.ui.control.Slider
        MaximumridgescaleSliderLabel    matlab.ui.control.Label
        MinimumridgescaleSlider         matlab.ui.control.Slider
        MinimumridgescaleLabel          matlab.ui.control.Label
        DataselectionPanel              matlab.ui.container.Panel
        AvailablefilesListBox           matlab.ui.control.ListBox
        AvailablefilesLabel             matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.Button
        DirectoryEditField              matlab.ui.control.EditField
        DirectoryEditFieldLabel         matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        %File-associated settings
        rootDir % Root directory
        currFile % Currently selected filename

        %Processing associated settings
        processSets % Settings used to apply network analysis algorithms
        dx % Spatial scale (pixel size) of currently selected image
        calibrationInc % Utility variable for sharing information from keyboard callback during width calibration
        currPt % Utility variable for sharing information from mouseclicks with app
        orients % Cell orientations, read from an external CellOrientations.csv file
        circCent % Centre of the detected circular structure in the image. Found if the circular orientations box is selected
        
        %GUI settings
        renderType % Currently selected rendering choice. Numerical - 'Raw' = 1, 'Flattened' = 2, 'Ridges' = 3, 'Network' = 4, 'Fibres' = 5
        deepestRender % The current deepest level of the analysis of the current image that is stored in memory. Numerical, matching renderType
        scaleBar % Whether to add a scalebar to the current render
        colourMap % The selected colourmap for the fibres (actual numerical values)
        fibreDataShow % The selected fibre information to display for the fibres
        hideBackground % Whether to hide the background in the current render (possible for the 'network' and 'fibre' render types)
        
        %Image data
        currFrame % Currently selected image (raw heightmap data)
        currFlat %Current flattened version of image
        currRidge %Currently detected ridge image
        currRidgeSeg %Currently segmented/skeletonized version of ridges - goes on to form basis of network
        currWidth %Currently measured local ridge width values (in pixels)
        currNsc %Currently detected ridge intensity scores

        %Network data
        rawNodes
        rawLinks % Network (graph representation) of current dataset
        noteNodes
        noteLinks % Annotated version of the network (with fibre IDs assigned)
        fibreProps

        %Additional bits of GUI
        colbar
    end

    methods (Access = private)
        %TESTFILES goes through the contents of the currently selected
        %directory and sees which files are in a readable format.
        function readableFiles = testFiles(app)
            candidates = dir(app.rootDir);
            readableFiles = {};

            %Following file extensions are permissible: .txt, .tif
            for i = 1:size(candidates,1)
                if numel(candidates(i).name) > 4
                    if sum(strcmp(candidates(i).name(end-3:end),{'.txt'})) > 0
                        try 
                            txtToMat(fullfile(app.rootDir,candidates(i).name));
                            readableFiles = [readableFiles;candidates(i).name];
                        catch
                            continue
                        end
                    end
                end
            end
        end

        %LOADIMG loads the currently selected image, along with pixel size
        %metadata.
        function [] = loadImg(app)
            fileExt = app.currFile(end-3:end);
            if strcmp(fileExt,'.txt')
                [AFMmat,dx] = txtToMat(fullfile(app.rootDir,app.currFile));
                origZ = AFMmat(:,:,3) - min(min(AFMmat(:,:,3))); %Sets the smallest value to zero
                rescaleFac = dx/app.processSets.standardScale;
                app.currFrame = imresize(origZ,rescaleFac);
                app.dx = app.processSets.standardScale;
            elseif strcmp(fileExt,'.tif')
                tifMat = imread(fullfile(app.rootDir,app.currFile));
                info = imfinfo(fullfile(app.rootDir,app.currFile));
                %Need to figure out how to get pixel size from
                %metadata, set to 1 for now
                app.dx = 1;
                app.currFrame = tifMat - min(min(tifMat));
            end

            if app.processSets.invert
                app.currFrame = max(app.currFrame(:))-app.currFrame;
            end
        end

        %RENDERVIEW renders the currently selected viewpoint on the
        %selected image.
        function [] = renderView(app)
            cla(app.UIAxes)
            if ~isempty(app.currFile) %Prevents errors from occuring if no data has been loaded so far
                switch app.renderType
                    case 1 %'Raw'
                        delete(app.colbar);
                        imagesc(app.UIAxes,app.currFrame)
                        colormap(app.UIAxes,'turbo')
                        clim(app.UIAxes,[prctile(app.currFrame(:),1),prctile(app.currFrame(:),99)])
                    case 2 %'Flattened'
                        delete(app.colbar);
                        imagesc(app.UIAxes,app.currFlat)
                        colormap(app.UIAxes,'turbo')
                        clim(app.UIAxes,[prctile(app.currFlat(:),1),prctile(app.currFlat(:),99)])
                    case 3 %'Ridges'
                        delete(app.colbar);
                        imgLow = prctile(app.currFlat(:),1);
                        imgHi = prctile(app.currFlat(:),99);
                        sharpImg = (app.currFlat-imgLow)/(imgHi-imgLow);

                        fibImg = cat(3,sharpImg,sharpImg-app.currRidge,sharpImg-app.currRidge);
                        imshow(fibImg,'Parent',app.UIAxes)
                    case 4 %'Network'
                        delete(app.colbar);
                        visualiseAnnotatedNetworkGUI(app.rawLinks,app.rawNodes,app.currFlat,app.hideBackground,app.UIAxes)
                    case 5 %'Fibres'
                        app.colbar = visualiseAnnotatedFibresGUI(app.fibreProps.(app.currFile(1:end-4)),app.noteNodes,app.noteLinks,app.currFlat,app.fibreDataShow,app.colourMap,app.hideBackground,app.dx,app.UIAxes);
                end

                axis(app.UIAxes,'tight')
                axis(app.UIAxes,'equal')

                app.UIAxes.XTick = [];
                app.UIAxes.YTick = [];
                app.UIAxes.YDir = 'reverse';

                if app.scaleBar
                    displayScaleBar(app.UIAxes,app.dx,app.currFrame)
                end
            end
        end

        %REFRESHANALYSIS updates the current set of analysed data to match a
        %newly selected image file and/or visualisation type
        function [] = refreshAnalysis(app)
            if ~isempty(app.currFile)
                if app.deepestRender < 1
                    app.loadImg();
                end
                if app.deepestRender < 2 && app.renderType > 1
                    app.calcFlattened();
                end
                if app.deepestRender < 3 && app.renderType > 2
                    app.calcRidges();
                end
                if app.deepestRender < 4 && app.renderType > 3
                    app.calcNetwork();
                end
                if app.deepestRender < 5 && app.renderType > 4
                    app.calcFibres();
                end
            end
            app.deepestRender = app.renderType;
        end

        %CALCFLATTENED calculates the flattened version of the input image
        %based on the currently selected flattening parameters
        function [] = calcFlattened(app)
            tmpFlat = app.currFrame - imgaussfilt(app.currFrame,app.processSets.flattenScale/app.dx);
            app.currFlat = tmpFlat - min(tmpFlat(:));
        end

        %CALCRIDGES calls up the ridge-detection framework using the
        %currently selected ridge values
        function [] = calcRidges(app)
            ridgeScale = linspace(app.processSets.minRidgeScale/app.dx,app.processSets.maxRidgeScale/app.dx,20); %Scales are in pixels
            [ridgeImg,app.currWidth,app.currNsc,app.currRidge] = bwRidgeCenterMod(app.currFlat,ridgeScale,app.processSets.waterThresh,app.dx);
            app.currRidgeSeg = bwskel(logical(ridgeImg),'MinBranchLength',round(2/app.dx));
        end

        %CALCNETWORK calls up the graph reconstruction and cleaning
        %framework using the currently detected ridges in the current image
        function [] = calcNetwork(app)
            [app.rawNodes,app.rawLinks] = makeAndCleanNetwork(app.currRidgeSeg,app.currFrame,app.currWidth,app.currNsc);
        end

        %CALCFIBRES annotates the fibres in the current network and
        %measures the fibre properties.
        function [] = calcFibres(app)
            %Find long fibres directly from network structure
            [app.noteNodes,app.noteLinks] = annotateNetwork(app.rawNodes,app.rawLinks,app.currRidge,app.processSets.splitBranches);
            %Find circle centre if circular orientations has been selected
            if app.processSets.alignCirc
                app.circCent = findCircleCentre(app.noteLinks,size(app.currFrame)*app.dx)*app.dx;
                if isnan(app.circCent) %Indicates manual selection is required
                    cla(app.UIAxes)
                    imagesc(app.UIAxes,app.currFlat)
                    colormap(app.UIAxes,'turbo')
                    clim(app.UIAxes,[prctile(app.currFlat(:),1),prctile(app.currFlat(:),99)])
                    
                    roi = drawcrosshair(app.UIAxes);
                    app.circCent = roi.Position*app.dx;
                    
                    cla(app.UIAxes,'reset')
                    app.UIAxes.Box = 'on';
                    app.UIAxes.XTick = [];
                    app.UIAxes.YTick = [];
                end
            else
                app.circCent = [];
            end
            %Measure properties of long fibres
            frameInd = find(strcmp(app.AvailablefilesListBox.Items,app.currFile));
            app.fibreProps.(app.currFile(1:end-4)) = measureFibres(app.noteNodes,app.noteLinks,app.currFrame,app.dx,app.processSets.alignLin*app.orients(frameInd),app.circCent/app.dx,app.processSets.widthCalibration);
        end

        %BATCHPROCESS applies the full processing pipeline to all the
        %datasets in the current root directory that can be analysed.
        function [] = batchProcess(app)
            for fileName = app.AvailablefilesListBox.Items
                app.currFile = fileName{1};
                app.loadImg();
                app.calcFlattened();
                app.calcRidges();
                app.calcNetwork();
                app.calcFibres();

                %Pick out the images for saving - don't do this during the
                %interactive stage to save on memory overheads.
                origImgs.(app.currFile(1:end-4)) = app.currFrame;
                flatImgs.(app.currFile(1:end-4)) = app.currFlat;
                nodes.(app.currFile(1:end-4)) = app.noteNodes;
                links.(app.currFile(1:end-4)) = app.noteLinks;
            end

            fibreProps = app.fibreProps;
            processSets = app.processSets;

            save(fullfile(app.rootDir,'foundFibres.mat'),'fibreProps','processSets','origImgs','flatImgs','nodes','links','-v7.3')

            msgbox('Batch successfully processed!')
        end

        %CALIBRATEWIDTHS runs the manual width calibration routine,
        %providing the calibration factor necessary to convert the
        %automatically detected fibre widths (based on the spatial scale of
        %a Gaussian kernal) to true (or at least human-acceptable) fibre
        %widths.
        function calibrationFactor = calibrateWidths(app)
            %Get the final version of the analysed fibre data (if not
            %already calculated).
            if app.deepestRender < 5
                tmpRend = app.renderType;
                app.renderType = 5;
                app.refreshAnalysis();
                app.renderType = tmpRend;
            end

            currLinks = app.noteLinks;

            %Get a list of candidate links (filtered by criteria such as
            %size and position)
            candL = [];
            candLSc = [];
            for L = 1:size(currLinks,2)
                if numel(currLinks(L).point) > 40
                    midX = mean(currLinks(L).comx);
                    midY = mean(currLinks(L).comy);
                    if midX > 30 && midX < size(app.currFlat,2)-30 && midY > 30 && midY < size(app.currFlat,1)-30
                        candL = [candL,L];
                        candLSc = [candLSc,mean(currLinks(L).Nscore)];
                    end
                end
            end

            %Ensure you aren't requiring people to do thousands of separate
            %fibres for the calibration - maximum 100. Only use the highest
            %scoring ones
            maxL = 100;
            if numel(candL) > maxL
                [~,candLOrd] = sort(candLSc,'descend');
                candL = candL(candLOrd(1:maxL));
            end

            %Loop through the list of selected fibres and get the computer
            %and user-generated guesses for the mid-fibre widths
            guesses = [];
            manuals = [];
            lInd = 1;

            currImg = app.currFlat';
            for L = candL
                cla(app.UIAxes)
                imagesc(app.UIAxes,currImg)
                caxis(app.UIAxes,[prctile(currImg(:),1),prctile(currImg(:),99)])
                colormap(app.UIAxes,'turbo')
                hold(app.UIAxes,'on')
                plot(app.UIAxes,currLinks(L).comy,currLinks(L).comx,'w.','MarkerSize',14)
                plot(app.UIAxes,currLinks(L).comy,currLinks(L).comx,'r.','MarkerSize',10)
                title(app.UIAxes,['Sample index: ', num2str(lInd), ' of 100.'])
                axis(app.UIAxes,[mean(currLinks(L).comy)-50,mean(currLinks(L).comy)+50,mean(currLinks(L).comx)-50,mean(currLinks(L).comx)+50])

                roi = drawpolyline(app.UIAxes,'Position',[mean(currLinks(L).comy)-20,mean(currLinks(L).comx)-20;mean(currLinks(L).comy)+20,mean(currLinks(L).comx)+20]);
                uiwait(app.UIFigure) %Will freeze the program until enter or space is hit (break the freeze via the UIFigureWindowKeyPress function)

                if app.calibrationInc
                    roiLen = sqrt((roi.Position(1,1)-roi.Position(2,1))^2 + (roi.Position(1,2)-roi.Position(2,2))^2);
                    guesses = [guesses;mean(currLinks(L).widths(11:end-10))]; %Snip off ends of links, where width detection is likely to be confused by connections to other fibres
                    manuals = [manuals;roiLen];
                end
                lInd = lInd + 1;
            end

            calibrationFactor = manuals\guesses;

            cla(app.UIAxes,'reset')
            hold(app.UIAxes,'on')
            plot(app.UIAxes,manuals,guesses,'r.','MarkerSize',15)
            plot(app.UIAxes,[0,max(manuals)],[0,max(manuals)*calibrationFactor],'k','LineWidth',1.5)
            title(app.UIAxes,'')
            xlabel(app.UIAxes,'Manually assigned width (pixels)')
            ylabel(app.UIAxes,'Automatically detected width (scale of Gaussian kernal)')

            correlation = corrcoef(manuals,guesses);
            text(app.UIAxes,5,max(guesses)-5,['R = ', num2str(correlation(1,2))])

            uiwait(app.UIFigure) %Will freeze the program until enter or space is hit (break the freeze via the UIFigureWindowKeyPress function)

            cla(app.UIAxes,'reset')
            app.UIAxes.Box = 'on';
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.renderView();
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.renderType = find(strcmp(app.VisualisationDropDown.Value,app.VisualisationDropDown.Items));
            app.colourMap = turbo;
            app.fibreDataShow = 'Length';
            app.hideBackground = false;
            app.currFile = [];

            app.processSets.flattenScale = app.FlatteningscaleSlider.Value;
            app.processSets.minRidgeScale = app.MinimumridgescaleSlider.Value;
            app.processSets.maxRidgeScale = app.MaximumridgescaleSlider.Value;
            app.processSets.waterThresh = app.WatershedthresholdSlider.Value;
            app.processSets.invert = app.InvertheightsCheckBox.Value;
            app.processSets.splitBranches = app.SplitbranchesCheckBox.Value;
            app.processSets.widthCalibration = 0.5;
            app.processSets.alignLin = false;
            app.processSets.alignCirc = false;
            app.processSets.standardScale = app.UpsampleddxEditField.Value;
            
            app.WatershedEdit.Value = app.WatershedthresholdSlider.Value;
            app.MaximumridgeEdit.Value = app.MaximumridgescaleSlider.Value;
            app.MinimumridgeEdit.Value = app.MinimumridgescaleSlider.Value;
            app.FlattenEdit.Value = app.FlatteningscaleSlider.Value;
            
            app.FibrecolourmapDropDown.Enable = 'off';
            app.FibredataDropDown.Enable = 'off';
            app.RemovebackgroundCheckBox.Enable = 'off';
            app.OrientfibreslinearCheckBox.Enable = 'off';

            app.Currentcalibrationfactor1Label.Text = {'Current calibration',['factor = ',num2str(app.processSets.widthCalibration)]};

            app.deepestRender = 0;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            tryDir = uigetdir();
            if tryDir ~= 0 %If the user didn't choose 'cancel' or 'x'
                app.rootDir = tryDir;
                app.DirectoryEditField.Value = app.rootDir;
                goodFiles = app.testFiles();
                app.AvailablefilesListBox.Items = goodFiles;
                
                %Decide whether to allow orientations to be corrected
                %based on cell orientation - only if you have a
                %CellOrientations.csv file in the root directory
                %containing orientations for all images in directory.
                if exist(fullfile(app.rootDir,'CellOrientations.csv'),'file') == 2
                    cellOrients = readmatrix(fullfile(app.rootDir,'CellOrientations.csv'),delimitedTextImportOptions);
                    %Check that all the images are represented in the
                    %orientations file
                    app.orients = nan(size(goodFiles));
                    for i = 1:size(goodFiles,1)
                        for j = 1:size(cellOrients,1)
                            if strcmp(goodFiles{i},cellOrients{j,1}) && isnumeric(str2double(cellOrients{j,2}))
                                app.orients(i) = deg2rad(str2double(cellOrients{j,2}));                                
                            end
                        end
                    end
                    if sum(isnan(app.orients)) == 0
                        app.OrientfibreslinearCheckBox.Enable = 'on';
                    else
                        app.OrientfibreslinearCheckBox.Enable = 'off';
                        app.OrientfibreslinearCheckBox.Value = false;
                        app.processSets.alignLin = false;
                        app.orients = zeros(size(goodFiles,1),1);
                    end
                else
                    app.OrientfibreslinearCheckBox.Enable = 'off';
                    app.OrientfibreslinearCheckBox.Value = false;
                    app.processSets.alignLin = false;
                    app.orients = zeros(size(goodFiles,1),1);
                end
                
                if ~isempty(goodFiles)
                    app.AvailablefilesListBox.Value = goodFiles{1};
                    app.currFile = goodFiles{1};
                    app.deepestRender = 0;
                    app.refreshAnalysis();
                    app.renderView();
                end
            end
        end

        % Value changed function: VisualisationDropDown
        function VisualisationDropDownValueChanged(app, event)
            app.renderType = find(strcmp(app.VisualisationDropDown.Value,app.VisualisationDropDown.Items));
            
            % Update checkbox, dropdown menu activation states
            if app.renderType >= 4
                app.RemovebackgroundCheckBox.Enable = 'on';
            else
                app.RemovebackgroundCheckBox.Enable = 'off';
            end
            
            if app.renderType == 5
                app.FibrecolourmapDropDown.Enable = 'on';
                app.FibredataDropDown.Enable = 'on';
            else
                app.FibrecolourmapDropDown.Enable = 'off';
                app.FibredataDropDown.Enable = 'off';
            end
            
            app.refreshAnalysis();
            app.renderView();
        end

        % Value changed function: AvailablefilesListBox
        function AvailablefilesListBoxValueChanged(app, event)
            app.currFile = app.AvailablefilesListBox.Value;
            app.deepestRender = 0;
            app.refreshAnalysis();
            app.renderView();
        end

        % Value changed function: DirectoryEditField
        function DirectoryEditFieldValueChanged(app, event)
            app.rootDir = app.DirectoryEditField.Value;
            if ~exist(app.rootDir,'dir')
                errordlg('No such directory exists...','Missing directory')
                app.rootDir = '';
                app.DirectoryEditField.Value = '';
            else
                goodFiles = app.testFiles();
                app.AvailablefilesListBox.Items = goodFiles;
                
                %Decide whether to allow orientations to be corrected
                %based on cell orientation - only if you have a
                %CellOrientations.csv file in the root directory
                %containing orientations for all images in directory.
                if exist(fullfile(app.rootDir,'CellOrientations.csv'),'file')
                    cellOrients = readmatrix(fullfile(app.rootDir,'CellOrientations.csv'),delimiterTextImportOptions);
                    %Check that all the images are represented in the
                    %orientations file
                    app.orients = nan(size(goodFiles));
                    for i = 1:size(goodFiles,1)
                        for j = 1:size(cellOrients,1)
                            if strcmp(goodFiles{i},cellOrients{j,1}) && isnumeric(cellOrients{j,2})
                                app.orients(i) = deg2rad(cellOrients{j,2});
                            end
                        end
                    end
                    if sum(isnan(app.orients)) == 0
                        app.OrientfibreslinearCheckBox.Enable = 'on';
                    else
                        app.OrientfibreslinearCheckBox.Enable = 'off';
                    end
                else
                    app.OrientfibreslinearCheckBox.Enable = 'off';
                    app.orients = zeros(size(goodFiles,1),1);
                end
                
                if ~isempty(goodFiles)
                    app.AvailablefilesListBox.Value = goodFiles{1};
                    app.currFile = goodFiles{1};
                    app.refreshAnalysis();
                    app.renderView();
                end
            end
        end

        % Value changed function: FlatteningscaleSlider
        function FlatteningscaleSliderValueChanged(app, event)
            app.processSets.flattenScale = app.FlatteningscaleSlider.Value;
            app.FlattenEdit.Value = app.FlatteningscaleSlider.Value;
            
            app.deepestRender = 1;
            app.refreshAnalysis();
            app.renderView();
        end

        % Value changed function: MinimumridgescaleSlider
        function MinimumridgescaleSliderValueChanged(app, event)
            app.processSets.minRidgeScale = app.MinimumridgescaleSlider.Value;
            app.MaximumridgescaleSlider.Limits(1) = max(app.processSets.minRidgeScale+2,5);
            app.MaximumridgeEdit.Value = app.MaximumridgescaleSlider.Value;
            
            if app.deepestRender > 2
                app.deepestRender = 2;
                app.refreshAnalysis();
            end
            app.renderView();
        end

        % Value changed function: MaximumridgescaleSlider
        function MaximumridgescaleSliderValueChanged(app, event)
            app.processSets.maxRidgeScale = app.MaximumridgescaleSlider.Value;
            app.MinimumridgescaleSlider.Limits(2) = max(app.processSets.maxRidgeScale-2,2);
            app.MaximumridgeEdit.Value = app.MaximumridgescaleSlider.Value;
            
            if app.deepestRender > 2
                app.deepestRender = 2;
                app.refreshAnalysis();
            end
            app.renderView();
        end

        % Value changed function: WatershedthresholdSlider
        function WatershedthresholdSliderValueChanged(app, event)
            app.processSets.waterThresh = app.WatershedthresholdSlider.Value;
            app.WatershedEdit.Value = app.WatershedthresholdSlider.Value;            
            
            if app.deepestRender > 2
                app.deepestRender = 2;
                app.refreshAnalysis();
            end
            app.renderView();
        end

        % Value changed function: InvertheightsCheckBox
        function InvertheightsCheckBoxValueChanged(app, event)
            app.processSets.invert = app.InvertheightsCheckBox.Value;
            app.deepestRender = 0;
            app.refreshAnalysis();
            app.renderView();
        end

        % Button pushed function: ProcessbatchButton
        function ProcessbatchButtonPushed(app, event)
            app.batchProcess();
        end

        % Value changed function: AddscalebarCheckBox
        function AddscalebarCheckBoxValueChanged(app, event)
            app.scaleBar = app.AddscalebarCheckBox.Value;
            app.renderView();
        end

        % Menu selected function: SavecurrentsettingsMenu
        function SavecurrentsettingsMenuSelected(app, event)
            [setFile,setPath] = uiputfile('fibrePropSettings.mat');
            if setFile ~= 0
                fibrePropsSettings = app.processSets;
                save(fullfile(setPath,setFile),'fibrePropsSettings');
            end
        end

        % Menu selected function: LoadsettingsMenu
        function LoadsettingsMenuSelected(app, event)
            [getFile,getPath] = uigetfile('*.mat','Choose fibre props settings file');
            load(fullfile(getPath,getFile))
            if ~exist('fibrePropsSettings','var')
                warndlg('Chosen file does not contain a fibre props settings file. Aborting load...')
            else
                app.processSets.flattenScale = fibrePropsSettings.flattenScale;
                app.FlatteningscaleSlider.Value = fibrePropsSettings.flattenScale;

                app.processSets.minRidgeScale = fibrePropsSettings.minRidgeScale;
                app.MinimumridgescaleSlider.Value = fibrePropsSettings.minRidgeScale;
                app.MaximumridgescaleSlider.Limits(1) = max(app.processSets.minRidgeScale+2,5);

                app.processSets.maxRidgeScale = fibrePropsSettings.maxRidgeScale;
                app.MaximumridgescaleSlider.Value = fibrePropsSettings.maxRidgeScale;
                app.MinimumridgescaleSlider.Limits(2) = max(app.processSets.maxRidgeScale-2,2);

                app.processSets.waterThresh = fibrePropsSettings.waterThresh;
                app.WatershedthresholdSlider.Value = fibrePropsSettings.waterThresh;

                app.processSets.invert = fibrePropsSettings.invert;
                app.InvertheightsCheckBox.Value = fibrePropsSettings.invert;

                app.processSets.splitBranches = fibrePropsSettings.splitBranches;
                app.SplitbranchesCheckBox.Value = fibrePropsSettings.splitBranches;

                app.processSets.widthCalibration = fibrePropsSettings.widthCalibration;
                
                %Decide whether to allow orientations to be corrected
                %based on cell orientation - only if you have a
                %CellOrientations.csv file in the root directory
                %containing orientations for all images in directory.
                if fibrePropsSettings.alignLin
                    if exist(fullfile(app.rootDir,'CellOrientations.csv'),'file') == 2
                        cellOrients = readmatrix(fullfile(app.rootDir,'CellOrientations.csv'),delimitedTextImportOptions);
                        %Check that all the images are represented in the
                        %orientations file
                        app.orients = nan(size(goodFiles));
                        for i = 1:size(goodFiles,1)
                            for j = 1:size(cellOrients,1)
                                if strcmp(goodFiles{i},cellOrients{j,1}) && isnumeric(str2double(cellOrients{j,2}))
                                    app.orients(i) = deg2rad(str2double(cellOrients{j,2}));
                                end
                            end
                        end
                        if sum(isnan(app.orients)) == 0
                            app.OrientfibreslinearCheckBox.Enable = 'on';
                        else
                            warndlg('CellOrientations.csv incorrectly formatted. Cell alignment will not be possible.')
                            app.OrientfibreslinearCheckBox.Enable = 'off';
                            app.OrientfibreslinearCheckBox.Value = false;
                            app.processSets.align = false;
                            app.orients = zeros(size(goodFiles,1),1);
                        end
                    else
                        warndlg('No CellOrientations.csv file available. Cell alignment will not be available.')
                        app.OrientfibreslinearCheckBox.Enable = 'off';
                        app.OrientfibreslinearCheckBox.Value = false;
                        app.processSets.align = false;
                        app.orients = zeros(size(goodFiles,1),1);
                    end
                end
            end
        end

        % Button pushed function: CalibratefibrewidthsButton
        function CalibratefibrewidthsButtonPushed(app, event)
            app.DisplayPanel.Enable = 'off';
            app.ProcessingparametersPanel.Enable = 'off';
            app.DataselectionPanel.Enable = 'off';
            app.CalibratefibrewidthsButton.Enable = 'off';
            app.ProcessbatchButton.Enable = 'off';

            app.processSets.widthCalibration = app.calibrateWidths();

            app.Currentcalibrationfactor1Label.Text = {'Current calibration',['factor = ',num2str(app.processSets.widthCalibration)]};

            app.DisplayPanel.Enable = 'on';
            app.ProcessingparametersPanel.Enable = 'on';
            app.DataselectionPanel.Enable = 'on';
            app.CalibratefibrewidthsButton.Enable = 'on';
            app.ProcessbatchButton.Enable = 'on';
        end

        % Value changed function: SplitbranchesCheckBox
        function SplitbranchesCheckBoxValueChanged(app, event)
            app.processSets.splitBranches = app.SplitbranchesCheckBox.Value;
            app.deepestRender = min(app.deepestRender,4);
            app.refreshAnalysis();
            app.renderView();
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            keyPressed = event.Key;

            if sum(strcmp(keyPressed,{'space','enter'})) > 0
                app.calibrationInc = true;
                uiresume(app.UIFigure);
            elseif strcmp(keyPressed,{'backspace'})
                app.calibrationInc = false;
                uiresume(app.UIFigure);
            end
        end

        % Value changed function: FibrecolourmapDropDown
        function FibrecolourmapDropDownValueChanged(app, event)
            value = app.FibrecolourmapDropDown.Value;

            switch value
                case 'Turbo'
                    app.colourMap = turbo;
                case 'Parula'
                    app.colourMap = parula;
                case 'HSV (circular)'
                    app.colourMap = hsv;
                case 'RedBlue (circular)'
                    app.colourMap = [readmatrix(fullfile('Colormaps','clwrm.csv'));flip(readmatrix(fullfile('Colormaps','clwrm.csv')))];
                case 'Phase (circular)'
                    app.colourMap = readmatrix(fullfile('Colormaps','phase.csv'));
                case 'Colorwheel (circular)'
                    app.colourMap = readmatrix(fullfile('Colormaps','colorwheel.csv'));
                case 'Ice-fire (circular)'
                    app.colourMap = readmatrix(fullfile('Colormaps','iceFire.csv'));
                case 'Twilight (circular)'
                    app.colourMap = readmatrix(fullfile('Colormaps','twilight.csv'));
            end

            app.renderView();
        end

        % Value changed function: FibredataDropDown
        function FibredataDropDownValueChanged(app, event)
            value = app.FibredataDropDown.Value;

            switch value
                case 'Local orientation'
                    app.fibreDataShow = 'localOrientation';
                case 'Global orientation'
                    app.fibreDataShow = 'meanOrientation';
                case 'Length'
                    app.fibreDataShow = 'Length';
            end
            
            app.renderView();   
        end

        % Value changed function: RemovebackgroundCheckBox
        function RemovebackgroundCheckBoxValueChanged(app, event)
            app.hideBackground = app.RemovebackgroundCheckBox.Value;
            app.renderView();
        end

        % Value changed function: OrientfibreslinearCheckBox
        function OrientfibreslinearCheckBoxValueChanged(app, event)
            app.processSets.alignLin = app.OrientfibreslinearCheckBox.Value;
            %Prevent simultaneous assignment of both alignment options
            if app.processSets.alignLin
                app.processSets.alignCirc = false;
                app.OrientfibrescircularCheckBox.Value = false;
            end
            app.deepestRender = min(app.deepestRender,4);
            app.refreshAnalysis();
            app.renderView();
        end

        % Menu selected function: SavecurrentviewMenu
        function SavecurrentviewMenuSelected(app, event)
            imgName = fullfile(app.rootDir,[app.currFile(1:end-4),'_visualised.tif']);
            exportgraphics(app.UIAxes,imgName,'Resolution',300)
        end

        % Value changed function: WatershedEdit
        function WatershedEditValueChanged(app, event)
            value = app.WatershedEdit.Value;
            
            %Run checks on input value
            if isnumeric(value)
                if value < app.WatershedthresholdSlider.Limits(1)
                    value = app.WatershedthresholdSlider.Limits(1);
                elseif value > app.WatershedthresholdSlider.Limits(2)
                    value = app.WatershedthresholdSlider.Limits(2);
                end
            else
                value = app.WatershedthresholdSlider.Value;
            end
            
            %Update values
            app.processSets.waterThresh = value;
            app.WatershedthresholdSlider.Value = value;
            app.WatershedEdit.Value = value;
            
            if app.deepestRender > 2
                app.deepestRender = 2;
                app.refreshAnalysis();
            end
            app.renderView();
        end

        % Value changed function: MaximumridgeEdit
        function MaximumridgeEditValueChanged(app, event)
            value = app.MaximumridgeEdit.Value;
            
            %Run checks on input value
            if isnumeric(value)
                if value < app.MaximumridgescaleSlider.Limits(1)
                    value = app.MaximumridgescaleSlider.Limits(1);
                elseif value > app.MaximumridgescaleSlider.Limits(2)
                    value = app.MaximumridgescaleSlider.Limits(2);
                end
            else
                value = app.MaximumridgescaleSlider.Value;
            end
            
            %Update values
            app.processSets.maxRidgeScale = value;
            app.MaximumridgescaleSlider.Value = value;
            app.MaximumridgeEdit.Value = value;            
            app.MinimumridgescaleSlider.Limits(2) = max(app.processSets.maxRidgeScale-2,2);
            
            if app.deepestRender > 2
                app.deepestRender = 2;
                app.refreshAnalysis();
            end
            app.renderView();
        end

        % Value changed function: MinimumridgeEdit
        function MinimumridgeEditValueChanged(app, event)
            value = app.MinimumridgeEdit.Value;
            
            %Run checks on input value
            if isnumeric(value)
                if value < app.MinimumridgescaleSlider.Limits(1)
                    value = app.MinimumridgescaleSlider.Limits(1);
                elseif value > app.MinimumridgescaleSlider.Limits(2)
                    value = app.MinimumridgescaleSlider.Limits(2);
                end
            else
                value = app.MinimumridgescaleSlider.Value;
            end
            
            %Update values
            app.processSets.minRidgeScale = value;
            app.MinimumridgescaleSlider.Value = value;
            app.MinimumridgeEdit.Value = value;
            app.MaximumridgescaleSlider.Limits(1) = max(app.processSets.minRidgeScale+2,5);
            
            if app.deepestRender > 2
                app.deepestRender = 2;
                app.refreshAnalysis();
            end
            app.renderView();
        end

        % Value changed function: FlattenEdit
        function FlattenEditValueChanged(app, event)
            value = app.FlattenEdit.Value;
            
            %Run checks on input value
            if isnumeric(value)
                if value < app.FlatteningscaleSlider.Limits(1)
                    value = app.FlatteningscaleSlider.Limits(1);
                elseif value > app.FlatteningscaleSlider.Limits(2)
                    value = app.FlatteningscaleSlider.Limits(2);
                end
            else
                value = app.FlatteningscaleSlider.Value;
            end
            
            %Update values
            app.processSets.flattenScale = value;
            app.FlatteningscaleSlider.Value = value;
            app.FlattenEdit.Value = value;
            
            if app.deepestRender > 1
                app.deepestRender = 1;
                app.refreshAnalysis();
            end
            app.renderView();
        end

        % Value changed function: OrientfibrescircularCheckBox
        function OrientfibrescircularCheckBoxValueChanged(app, event)
            app.processSets.alignCirc = app.OrientfibrescircularCheckBox.Value;
            %Prevent simultaneous assignment of both alignment options
            if app.processSets.alignCirc
                app.processSets.alignLin = false;
                app.OrientfibreslinearCheckBox.Value = false;
            end
            app.deepestRender = min(app.deepestRender,4);
            app.refreshAnalysis();
            app.renderView();
        end

        % Value changed function: UpsampleddxEditField
        function UpsampleddxEditFieldValueChanged(app, event)
            app.processSets.standardScale = app.UpsampleddxEditField.Value;
            if app.processSets.standardScale < 0.1
                app.processSets.standardScale = 0.1;
                app.UpsampleddxEditField.Value = 0.1;
            end
            app.deepestRender = 0;
            app.refreshAnalysis();
            app.renderView();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SavecurrentsettingsMenu = uimenu(app.FileMenu);
            app.SavecurrentsettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SavecurrentsettingsMenuSelected, true);
            app.SavecurrentsettingsMenu.Text = 'Save current settings';
            app.LoadsettingsMenu = uimenu(app.FileMenu);
            app.LoadsettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadsettingsMenuSelected, true);
            app.LoadsettingsMenu.Text = 'Load settings';
            app.SavecurrentviewMenu = uimenu(app.FileMenu);
            app.SavecurrentviewMenu.MenuSelectedFcn = createCallbackFcn(app, @SavecurrentviewMenuSelected, true);
            app.SavecurrentviewMenu.Text = 'Save current view';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.DataselectionPanel = uipanel(app.UIFigure);
            app.DataselectionPanel.Title = 'Data selection';
            app.DirectoryEditFieldLabel = uilabel(app.DataselectionPanel);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.DataselectionPanel, 'text');
            app.DirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @DirectoryEditFieldValueChanged, true);
            app.BrowseButton = uibutton(app.DataselectionPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.AvailablefilesLabel = uilabel(app.DataselectionPanel);
            app.AvailablefilesLabel.Text = {'Available'; 'files'};
            app.AvailablefilesListBox = uilistbox(app.DataselectionPanel);
            app.AvailablefilesListBox.Items = {};
            app.AvailablefilesListBox.ValueChangedFcn = createCallbackFcn(app, @AvailablefilesListBoxValueChanged, true);
            app.AvailablefilesListBox.Value = {};
            app.ProcessingparametersPanel = uipanel(app.UIFigure);
            app.ProcessingparametersPanel.Title = 'Processing parameters';
            app.MinimumridgescaleLabel = uilabel(app.ProcessingparametersPanel);
            app.MinimumridgescaleLabel.Text = {'Minimum'; 'ridge scale'};
            app.MinimumridgescaleSlider = uislider(app.ProcessingparametersPanel);
            app.MinimumridgescaleSlider.Limits = [2 18];
            app.MinimumridgescaleSlider.ValueChangedFcn = createCallbackFcn(app, @MinimumridgescaleSliderValueChanged, true);
            app.MinimumridgescaleSlider.Value = 8;
            app.MaximumridgescaleSliderLabel = uilabel(app.ProcessingparametersPanel);
            app.MaximumridgescaleSliderLabel.Text = {'Maximum'; 'ridge scale'};
            app.MaximumridgescaleSlider = uislider(app.ProcessingparametersPanel);
            app.MaximumridgescaleSlider.Limits = [5 50];
            app.MaximumridgescaleSlider.ValueChangedFcn = createCallbackFcn(app, @MaximumridgescaleSliderValueChanged, true);
            app.MaximumridgescaleSlider.Value = 30;
            app.FlatteningscaleSliderLabel = uilabel(app.ProcessingparametersPanel);
            app.FlatteningscaleSliderLabel.Text = {'Flattening'; 'scale'};
            app.FlatteningscaleSlider = uislider(app.ProcessingparametersPanel);
            app.FlatteningscaleSlider.Limits = [0.4 10];
            app.FlatteningscaleSlider.ValueChangedFcn = createCallbackFcn(app, @FlatteningscaleSliderValueChanged, true);
            app.FlatteningscaleSlider.Value = 4;
            app.InvertheightsCheckBox = uicheckbox(app.ProcessingparametersPanel);
            app.InvertheightsCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvertheightsCheckBoxValueChanged, true);
            app.InvertheightsCheckBox.Text = 'Invert heights';
            app.SplitbranchesCheckBox = uicheckbox(app.ProcessingparametersPanel);
            app.SplitbranchesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SplitbranchesCheckBoxValueChanged, true);
            app.SplitbranchesCheckBox.Text = 'Split branches';
            app.SplitbranchesCheckBox.Value = true;
            app.WatershedthresholdLabel = uilabel(app.ProcessingparametersPanel);
            app.WatershedthresholdLabel.Text = {'Watershed '; 'threshold'};
            app.WatershedthresholdSlider = uislider(app.ProcessingparametersPanel);
            app.WatershedthresholdSlider.Limits = [1 8];
            app.WatershedthresholdSlider.MajorTicks = [1 2 3 4 5 6 7 8];
            app.WatershedthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @WatershedthresholdSliderValueChanged, true);
            app.WatershedthresholdSlider.Value = 6;
            app.OrientfibreslinearCheckBox = uicheckbox(app.ProcessingparametersPanel);
            app.OrientfibreslinearCheckBox.ValueChangedFcn = createCallbackFcn(app, @OrientfibreslinearCheckBoxValueChanged, true);
            app.OrientfibreslinearCheckBox.Text = 'Orient fibres (linear)';
            app.FlattenEdit = uieditfield(app.ProcessingparametersPanel, 'numeric');
            app.FlattenEdit.ValueChangedFcn = createCallbackFcn(app, @FlattenEditValueChanged, true);
            app.MinimumridgeEdit = uieditfield(app.ProcessingparametersPanel, 'numeric');
            app.MinimumridgeEdit.ValueChangedFcn = createCallbackFcn(app, @MinimumridgeEditValueChanged, true);
            app.MaximumridgeEdit = uieditfield(app.ProcessingparametersPanel, 'numeric');
            app.MaximumridgeEdit.ValueChangedFcn = createCallbackFcn(app, @MaximumridgeEditValueChanged, true);
            app.WatershedEdit = uieditfield(app.ProcessingparametersPanel, 'numeric');
            app.WatershedEdit.ValueChangedFcn = createCallbackFcn(app, @WatershedEditValueChanged, true);
            app.OrientfibrescircularCheckBox = uicheckbox(app.ProcessingparametersPanel);
            app.OrientfibrescircularCheckBox.ValueChangedFcn = createCallbackFcn(app, @OrientfibrescircularCheckBoxValueChanged, true);
            app.OrientfibrescircularCheckBox.Text = 'Orient fibres (circular)';
            app.UpsampleddxEditFieldLabel = uilabel(app.ProcessingparametersPanel);
            app.UpsampleddxEditFieldLabel.Text = 'Upsampled dx';
            app.UpsampleddxEditField = uieditfield(app.ProcessingparametersPanel, 'numeric');
            app.UpsampleddxEditField.ValueChangedFcn = createCallbackFcn(app, @UpsampleddxEditFieldValueChanged, true);
            app.UpsampleddxEditField.Value = 0.2;
            app.DisplayPanel = uipanel(app.UIFigure);
            app.DisplayPanel.Title = 'Display';
            app.VisualisationDropDownLabel = uilabel(app.DisplayPanel);
            app.VisualisationDropDownLabel.Text = 'Visualisation';
            app.VisualisationDropDown = uidropdown(app.DisplayPanel);
            app.VisualisationDropDown.Items = {'Raw', 'Flattened', 'Ridges', 'Network', 'Fibres'};
            app.VisualisationDropDown.ValueChangedFcn = createCallbackFcn(app, @VisualisationDropDownValueChanged, true);
            app.VisualisationDropDown.Value = 'Raw';
            app.AddscalebarCheckBox = uicheckbox(app.DisplayPanel);
            app.AddscalebarCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddscalebarCheckBoxValueChanged, true);
            app.AddscalebarCheckBox.Text = 'Add scalebar';
            app.FibredataDropDownLabel = uilabel(app.DisplayPanel);
            app.FibredataDropDownLabel.Text = 'Fibre data';
            app.FibredataDropDown = uidropdown(app.DisplayPanel);
            app.FibredataDropDown.Items = {'Length', 'Local orientation', 'Global orientation'};
            app.FibredataDropDown.ValueChangedFcn = createCallbackFcn(app, @FibredataDropDownValueChanged, true);
            app.FibredataDropDown.Value = 'Length';
            app.FibrecolourmapDropDownLabel = uilabel(app.DisplayPanel);
            app.FibrecolourmapDropDownLabel.Text = 'Fibre colourmap';
            app.FibrecolourmapDropDown = uidropdown(app.DisplayPanel);
            app.FibrecolourmapDropDown.Items = {'Turbo', 'Parula', 'HSV (circular)', 'RedBlue (circular)', 'Phase (circular)', 'Colorwheel (circular)', 'Ice-fire (circular)', 'Twilight (circular)'};
            app.FibrecolourmapDropDown.ValueChangedFcn = createCallbackFcn(app, @FibrecolourmapDropDownValueChanged, true);
            app.FibrecolourmapDropDown.Value = 'Turbo';
            app.RemovebackgroundCheckBox = uicheckbox(app.DisplayPanel);
            app.RemovebackgroundCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemovebackgroundCheckBoxValueChanged, true);
            app.RemovebackgroundCheckBox.Text = 'Remove background';
            app.ProcessbatchButton = uibutton(app.UIFigure, 'push');
            app.ProcessbatchButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessbatchButtonPushed, true);
            app.ProcessbatchButton.Text = 'Process batch';
            app.CalibratefibrewidthsButton = uibutton(app.UIFigure, 'push');
            app.CalibratefibrewidthsButton.ButtonPushedFcn = createCallbackFcn(app, @CalibratefibrewidthsButtonPushed, true);
            app.CalibratefibrewidthsButton.Text = 'Calibrate fibre widths';
            app.Currentcalibrationfactor1Label = uilabel(app.UIFigure);
            app.Currentcalibrationfactor1Label.Text = {'Current calibration '; 'factor: 1'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fibreFinder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end