classdef fibreProps < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ProcessbatchButton            matlab.ui.control.Button
        DisplayPanel                  matlab.ui.container.Panel
        VisualisationDropDown         matlab.ui.control.DropDown
        VisualisationDropDownLabel    matlab.ui.control.Label
        ProcessingparametersPanel     matlab.ui.container.Panel
        SplitbranchesCheckBox         matlab.ui.control.CheckBox
        InvertheightmapCheckBox       matlab.ui.control.CheckBox
        FlatteningscaleSlider         matlab.ui.control.Slider
        FlatteningscaleSliderLabel    matlab.ui.control.Label
        MaximumridgescaleSlider       matlab.ui.control.Slider
        MaximumridgescaleSliderLabel  matlab.ui.control.Label
        MinimumridgescaleSlider       matlab.ui.control.Slider
        MinimumridgescaleLabel        matlab.ui.control.Label
        DataselectionPanel            matlab.ui.container.Panel
        ListBox                       matlab.ui.control.ListBox
        ListBoxLabel                  matlab.ui.control.Label
        BrowseButton                  matlab.ui.control.Button
        DirectoryEditField            matlab.ui.control.EditField
        DirectoryEditFieldLabel       matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %File-associated settings
        rootDir % Root directory
        currFile % Currently selected filename

        %Processing associated settings
        processSets % Settings used to apply network analysis algorithms
        dx % Spatial scale (pixel size) of currently selected image

        %GUI settings
        renderType % Currently selected rendering choice

        %Image data
        currFrame % Currently selected image (raw data)
        currFlat %Current flattened version of image
        currRidge %Currently detected ridge image
    end
    
    methods (Access = private)
        %TESTFILES goes through the contents of the currently selected
        %directory and sees which files are in a readable format.
        function readableFiles = testFiles(app)
            candidates = dir(app.rootDir);
            readableFiles = {};

            %Following file extensions are permissible: .txt, .tif
            for i = 1:size(candidates,1)
                if numel(candidates(i).name) > 4
                    if sum(strcmp(candidates(i).name(end-3:end),{'.txt','.tif'})) > 0
                        readableFiles = [readableFiles;candidates(i).name];
                    end
                end
            end
        end

        %LOADIMG loads the currently selected image, along with pixel size
        %metadata.
        function [] = loadImg(app)
            fileExt = app.currFile(end-3:end);
            if strcmp(fileExt,'.txt')
                [AFMmat,app.dx] = txtToMat(app.currFile);
                app.currFrame = AFMmat(:,:,3) - min(min(AFMmat(:,:,3))); %Sets the smallest value to zero
            elseif strcmp(fileExt,'.tif')
                tifMat = imread(app.currFile);
                info = imfinfo(app.currFile);
                %Need to figure out how to get pixel size from
                %metadata, set to 1 for now
                app.dx = 1;
                app.currFrame = tifMat - min(min(tifMat));
            end
        end

        %RENDERVIEW renders the currently selected viewpoint on the
        %selected image.
        function [] = renderView(app)
            switch app.renderType
                case 'Raw'
                    imagesc(app.UIAxes,app.currFrame)
                    colormap(app.UIAxes,'turbo')
                case 'Flattened'
                    imagesc(app.UIAxes,app.currFlat)
                    colormap(app.UIAxes,'turbo')
                case 'Ridges'
                case 'Network'
                case 'Fibres'
            end

            axis(app.UIAxes,'tight')
            axis(app.UIAxes,'equal')
            
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
        end
    
        %UPDATEANALYSIS updates the current set of analysed data to match a
        %newly selected image file, depending on the depth of the analysis
        %the visualisation tool is currently asked to show.
        function [] = updateAnalysis(app)
            switch app.renderType
                case 'Raw'
                    app.loadImg();
                case 'Flattened'
                    app.loadImg();
                    app.calcFlattened();
                case 'Ridges'
                    app.loadImg();
                    app.calcFlattened();
                    app.calcRidges();
                case 'Network'
                    app.loadImg();
                    app.calcFlattened();
                    app.calcRidges();
                case 'Fibres'
                    app.loadImg();
                    app.calcFlattened();
                    app.calcRidges();
            end
        end

        %CALCFLATTENED calculates the flattened version of the input image
        %based on the currently selected flattening parameters
        function [] = calcFlattened(app)
            tmpFlat = app.currFrame - imgaussfilt(app.currFrame,app.processSets.flattenScale);
            app.currFlat = tmpFlat - min(tmpFlat(:));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.renderType = app.VisualisationDropDown.Value;
            app.processSets.flattenScale = app.FlatteningscaleSlider.Value;
            app.processSets.minRidgeScale = app.MinimumridgescaleSlider.Value;
            app.processSets.maxRidgeScael = app.MaximumridgescaleSlider.Value;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.rootDir = uigetdir();
            app.DirectoryEditField.Value = app.rootDir;
            goodFiles = app.testFiles();
            app.ListBox.Items = goodFiles;
            if ~isempty(goodFiles)
                app.ListBox.Value = goodFiles{1};
                app.currFile = fullfile(app.rootDir,goodFiles{1});
                app.updateAnalysis();
                app.renderView();
            end
        end

        % Value changed function: VisualisationDropDown
        function VisualisationDropDownValueChanged(app, event)
            app.renderType = app.VisualisationDropDown.Value;
            app.renderView();
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.currFile = fullfile(app.rootDir,app.ListBox.Value);
            app.updateAnalysis();
            app.renderView();
        end

        % Value changed function: DirectoryEditField
        function DirectoryEditFieldValueChanged(app, event)
            app.rootDir = app.DirectoryEditField.Value;
            if ~exist(app.rootDir,'dir')
                errordlg('No such directory exists...','Missing directory')
                app.rootDir = '';
                app.DirectoryEditField.Value = '';
            else
                goodFiles = app.testFiles();
                app.ListBox.Items = goodFiles;
                if ~isempty(goodFiles)
                    app.ListBox.Value = goodFiles{1};
                    app.currFile = fullfile(app.rootDir,goodFiles{1});
                    app.updateAnalysis();
                    app.renderView();
                end
            end
        end

        % Value changed function: FlatteningscaleSlider
        function FlatteningscaleSliderValueChanged(app, event)
            app.processSets.flattenScale = app.FlatteningscaleSlider.Value;
            app.calcFlattened();
            app.renderView();            
        end

        % Value changed function: MinimumridgescaleSlider
        function MinimumridgescaleSliderValueChanged(app, event)
            app.processSets.minRidgeScale = app.MinimumridgescaleSlider.Value;
            app.calcRidges();
            app.renderView();
        end

        % Value changed function: MaximumridgescaleSlider
        function MaximumridgescaleSliderValueChanged(app, event)
            app.processSets.maxRidgeScale = app.MaximumridgescaleSlider.Value;
            app.calcRidges();
            app.renderView();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.DataselectionPanel = uipanel(app.UIFigure);
            app.DataselectionPanel.Title = 'Data selection';
            app.DirectoryEditFieldLabel = uilabel(app.DataselectionPanel);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.DataselectionPanel, 'text');
            app.DirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @DirectoryEditFieldValueChanged, true);
            app.BrowseButton = uibutton(app.DataselectionPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.ListBoxLabel = uilabel(app.DataselectionPanel);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.DataselectionPanel);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.ProcessingparametersPanel = uipanel(app.UIFigure);
            app.ProcessingparametersPanel.Title = 'Processing parameters';
            app.MinimumridgescaleLabel = uilabel(app.ProcessingparametersPanel);
            app.MinimumridgescaleLabel.Text = {'Minimum'; 'ridge scale'};
            app.MinimumridgescaleSlider = uislider(app.ProcessingparametersPanel);
            app.MinimumridgescaleSlider.Limits = [12 100];
            app.MinimumridgescaleSlider.ValueChangedFcn = createCallbackFcn(app, @MinimumridgescaleSliderValueChanged, true);
            app.MinimumridgescaleSlider.Value = 12;
            app.MaximumridgescaleSliderLabel = uilabel(app.ProcessingparametersPanel);
            app.MaximumridgescaleSliderLabel.Text = {'Maximum'; 'ridge scale'};
            app.MaximumridgescaleSlider = uislider(app.ProcessingparametersPanel);
            app.MaximumridgescaleSlider.Limits = [12 100];
            app.MaximumridgescaleSlider.ValueChangedFcn = createCallbackFcn(app, @MaximumridgescaleSliderValueChanged, true);
            app.MaximumridgescaleSlider.Value = 12;
            app.FlatteningscaleSliderLabel = uilabel(app.ProcessingparametersPanel);
            app.FlatteningscaleSliderLabel.Text = {'Flattening'; 'scale'};
            app.FlatteningscaleSlider = uislider(app.ProcessingparametersPanel);
            app.FlatteningscaleSlider.Limits = [2 100];
            app.FlatteningscaleSlider.ValueChangedFcn = createCallbackFcn(app, @FlatteningscaleSliderValueChanged, true);
            app.FlatteningscaleSlider.Value = 2;
            app.InvertheightmapCheckBox = uicheckbox(app.ProcessingparametersPanel);
            app.InvertheightmapCheckBox.Text = 'Invert heightmap';
            app.SplitbranchesCheckBox = uicheckbox(app.ProcessingparametersPanel);
            app.SplitbranchesCheckBox.Text = 'Split branches';
            app.DisplayPanel = uipanel(app.UIFigure);
            app.DisplayPanel.Title = 'Display';
            app.VisualisationDropDownLabel = uilabel(app.DisplayPanel);
            app.VisualisationDropDownLabel.Text = 'Visualisation';
            app.VisualisationDropDown = uidropdown(app.DisplayPanel);
            app.VisualisationDropDown.Items = {'Raw', 'Flattened', 'Ridges', 'Network', 'Fibres'};
            app.VisualisationDropDown.ValueChangedFcn = createCallbackFcn(app, @VisualisationDropDownValueChanged, true);
            app.VisualisationDropDown.Value = 'Raw';
            app.ProcessbatchButton = uibutton(app.UIFigure, 'push');
            app.ProcessbatchButton.Text = 'Process batch';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fibreProps

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end